classdef projekt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        imgOriginalLabel           matlab.ui.control.Label
        imgSegmentedLabel          matlab.ui.control.Label
        loadButton                 matlab.ui.control.Button
        saveButton                 matlab.ui.control.Button
        ParametrKLabel             matlab.ui.control.Label
        KSlider                    matlab.ui.control.Slider
        SegmentButton              matlab.ui.control.Button
        LiczbaiteracjiSliderLabel  matlab.ui.control.Label
        iterSlider                 matlab.ui.control.Slider
        KField                     matlab.ui.control.EditField
        iterField                  matlab.ui.control.EditField
        UIimgSegmented             matlab.ui.control.UIAxes
        UIImgOriginal              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imgOriginal % matrix that stores original image
        imgSegmented % matrix for segmentation operations
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadButton
        function LoadImage(app, event)
            [filename, filepath] = uigetfile({'*.jpg';'*.jpeg';'*.png'}, 'Select File to Open');
            if ~isequal(filename,0) && ~isequal(filepath,0)
                fullname = [filepath, filename];
                 app.imgOriginal = double(imread(fullname))/255;               
                imshow(app.imgOriginal, 'Parent', app.UIImgOriginal);
                cla(app.UIimgSegmented);
            end
        end

        % Button pushed function: saveButton
        function SaveImage(app, event)
            [filename, filepath] = uiputfile({'*.jpg';'*.jpeg';'*.png'}, 'Select Folder to Save');
            if ~isequal(filename,0) && ~isequal(filepath,0)
                newfilename = fullfile(filepath, filename);
                imwrite(app.imgSegmented, newfilename);
            end
        end

        % Button pushed function: SegmentButton
        function Segmentation(app, event)
             if ~isempty(app.imgOriginal)
                [height, width] = size(app.imgOriginal, 1, 2);
                pixelCount = height*width;
                
                imgFlat = reshape(app.imgOriginal,pixelCount,3);     % image in 2D matrix
                K = int64(app.KSlider.Value);                        % number of clusters
                centroids = imgFlat(randi(pixelCount, K, 1),:);      % cluster centroids
                distances = zeros(pixelCount,K);                     % distances
                labels = zeros(pixelCount, 1);                       % labels = cluster numbers
                iterMax = int64(app.iterSlider.Value);               % number of iterations
                
                for iter = 1:iterMax
                   for pixel = 1:pixelCount
                      for k = 1:K
                          distances(pixel,k) = norm(imgFlat(pixel,:) - centroids(k,:));
                      end
                      [~, label] = min(distances(pixel,1:K));
                      labels(pixel) = label;
                   end

                   for k = 1:K
                      clusterPixels = labels == k;
                      if isempty(clusterPixels)
                          centroids(k, :) = imgFlat(randi(pixelCount, 1),:);
                      else
                          centroids(k,:) = mean(imgFlat(clusterPixels,:));
                      end
                   end
                end
                
                imgSegFlat = zeros(pixelCount, 3);
                for k = 1:K
                    clusterPixels = find(labels == k);
                    imgSegFlat(clusterPixels,:) = repmat(centroids(k,:),size(clusterPixels,1),1);
                end

                app.imgSegmented = reshape(imgSegFlat,height,width,3);
                imshow(app.imgSegmented, 'Parent', app.UIimgSegmented);
             end
        end

        % Value changed function: iterSlider
        function iterSliderValueChanged(app, event)
            s1 = 'it =';
            s2 = num2str(int64(app.iterSlider.Value));
            answer = s1 + " " + s2;
            app.iterField.Value = answer;
        end

        % Value changed function: KSlider
        function KSliderValueChanged(app, event)
            s1 = 'k =';
            s2 = num2str(int64(app.KSlider.Value));
            answer = s1 + " " + s2;
            app.KField.Value = answer;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Segmentacja barwna metodą k-średnich';
            app.imgOriginalLabel = uilabel(app.UIFigure);
            app.imgOriginalLabel.Text = 'Oryginalny obraz';
            app.imgSegmentedLabel = uilabel(app.UIFigure);
            app.imgSegmentedLabel.Text = 'Obraz po segmentacji barwnej';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImage, true);
            app.loadButton.Text = 'Wczytaj obraz';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImage, true);
            app.saveButton.Text = 'Zapisz obraz';
            app.ParametrKLabel = uilabel(app.UIFigure);
            app.ParametrKLabel.Text = 'Parametr K';
            app.KSlider = uislider(app.UIFigure);
            app.KSlider.Limits = [1 30];
            app.KSlider.MajorTicks = [1 10 20 30 40 50];
            app.KSlider.ValueChangedFcn = createCallbackFcn(app, @KSliderValueChanged, true);
            app.KSlider.MinorTicks = [1 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50];
            app.KSlider.Value = 15;
            app.SegmentButton = uibutton(app.UIFigure, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @Segmentation, true);
            app.SegmentButton.Text = 'Segmentacja';
            app.LiczbaiteracjiSliderLabel = uilabel(app.UIFigure);
            app.LiczbaiteracjiSliderLabel.Text = 'Liczba iteracji';
            app.iterSlider = uislider(app.UIFigure);
            app.iterSlider.Limits = [1 20];
            app.iterSlider.MajorTicks = [1 10 20];
            app.iterSlider.ValueChangedFcn = createCallbackFcn(app, @iterSliderValueChanged, true);
            app.iterSlider.Value = 10;
            app.KField = uieditfield(app.UIFigure, 'text');
            app.KField.Editable = 'off';
            app.KField.Value = 'k = 15';
            app.iterField = uieditfield(app.UIFigure, 'text');
            app.iterField.Editable = 'off';
            app.iterField.Value = 'it = 10';
            app.UIimgSegmented = uiaxes(app.UIFigure);
            zlabel(app.UIimgSegmented, 'Z')
            app.UIimgSegmented.XTick = [];
            app.UIimgSegmented.YTickLabel = '';
            app.UIImgOriginal = uiaxes(app.UIFigure);
            zlabel(app.UIImgOriginal, 'Z')
            app.UIImgOriginal.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIImgOriginal.XTickLabel = '';
            app.UIImgOriginal.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIImgOriginal.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projekt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end